classdef oven_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Slot1CheckBox             matlab.ui.control.CheckBox
        Slot2CheckBox             matlab.ui.control.CheckBox
        Slot3CheckBox             matlab.ui.control.CheckBox
        Slot4CheckBox             matlab.ui.control.CheckBox
        Slot5CheckBox             matlab.ui.control.CheckBox
        Slot6CheckBox             matlab.ui.control.CheckBox
        Slot7CheckBox             matlab.ui.control.CheckBox
        Slot8CheckBox             matlab.ui.control.CheckBox
        Slot9CheckBox             matlab.ui.control.CheckBox
        Slot10CheckBox            matlab.ui.control.CheckBox
        Slot11CheckBox            matlab.ui.control.CheckBox
        Slot12CheckBox            matlab.ui.control.CheckBox
        PaperColourDropDownLabel  matlab.ui.control.Label
        PaperColourDropDown       matlab.ui.control.DropDown
        OvenAssitaBotLabel        matlab.ui.control.Label
        Joint1SliderLabel         matlab.ui.control.Label
        Joint1Slider              matlab.ui.control.Slider
        Joint2SliderLabel         matlab.ui.control.Label
        Joint2Slider              matlab.ui.control.Slider
        Joint3SliderLabel         matlab.ui.control.Label
        Joint3Slider              matlab.ui.control.Slider
        Joint4SliderLabel         matlab.ui.control.Label
        Joint4Slider              matlab.ui.control.Slider
        Joint5SliderLabel         matlab.ui.control.Label
        Joint5Slider              matlab.ui.control.Slider
        Joint6SliderLabel         matlab.ui.control.Label
        Joint6Slider              matlab.ui.control.Slider
        JointMovementLabel        matlab.ui.control.Label
        EndEffectorControlLabel   matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        UpdateJointsButton        matlab.ui.control.Button
        UpdatePositionButton      matlab.ui.control.Button
        CupcakePrepLabel          matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        ESTOPButton               matlab.ui.control.Button
        JoystickControlButton     matlab.ui.control.Button
        EResumeButton             matlab.ui.control.Button
        Joint7SliderLabel         matlab.ui.control.Label
        Joint7Slider              matlab.ui.control.Slider
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.NumericEditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.NumericEditField
        YawEditFieldLabel         matlab.ui.control.Label
        YawEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        control; % Description
        q_joint_slider = [0 0 0 0 0 0];
        x_goal;
        y_goal;
        z_goal;
        roll_goal;
        pitch_goal;
        yaw_goal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all
            %close all
            %clc
            app.control = Spawn;

            
        end

        % Button pushed function: STARTButton
        function StartEvent(app, event)
            %Start function things
            %gp7_ = UR5;
            %gp7_.model.base = transl(-0.25,0.15,0.84);
            %hold on
            %Plot as per UR5 function
            % gp7_.PlotAndColourRobot();
            
            %Nathan
            %Call functions to do the things
            %1. Pick up tray
            %2. Move to oven local
            
            %Spawn Cakes
            app.control.CakeLocationSpawn();
            
            app.x_goal = app.control.tray_x;
            app.y_goal = app.control.tray_y;
            app.z_goal = app.control.tray_z+0.1;


            %app.control.robot.RMRCPoint(app.x_goal,app.y_goal,app.z_goal);
            
            app.control.robot.mode=2;
            %Vague point of oven
            
            %app.control.SpawnRMRCPoint(0.45,0.4,0.9);
            
            %app.control.robot.RMRCPoint(0.45,0.4,0.9);
            
            %q_value = [0.1997   -1.0494    1.3558   -2.3072   -1.5709   -1.7705];
            %app.control.RobotPoseAnimate(q_value);
            %pause(3);
            %q_value = [-0.1509    1.5708   -0.1906    2.9497    1.5706   -4.5613];
            %app.control.RobotPoseAnimate(q_value);
            
            %Problem is that control is defined here, and so is not able to
            %be used in other functions. Must be defined at startup of GUI
            %instead.
            
        end

        % Button pushed function: ESTOPButton
        function EmergencyStop(app, event)
            app.control.robot.stop_status = 1;
            
            %Make a certain value a certain thing
            
        end

        % Value changed function: Slot1CheckBox
        function CakeSlot1(app, event)
            value = app.Slot1CheckBox.Value;
            app.control.CakeSlotChoice(12);
            
        end

        % Value changed function: Slot2CheckBox
        function CakeSlot2(app, event)
            value = app.Slot2CheckBox.Value;
            app.control.CakeSlotChoice(4);
            
        end

        % Value changed function: Slot3CheckBox
        function CakeSlot3(app, event)
            value = app.Slot3CheckBox.Value;
            app.control.CakeSlotChoice(8);
            
        end

        % Value changed function: Slot4CheckBox
        function CakeSlot4(app, event)
            value = app.Slot4CheckBox.Value;
            app.control.CakeSlotChoice(11);
            
        end

        % Value changed function: Slot5CheckBox
        function CakeSlot5(app, event)
            value = app.Slot5CheckBox.Value;
            app.control.CakeSlotChoice(3);
            
        end

        % Value changed function: Slot6CheckBox
        function CakeSlot6(app, event)
            value = app.Slot6CheckBox.Value;
            app.control.CakeSlotChoice(7);
            
        end

        % Value changed function: Slot7CheckBox
        function CakeSlot7(app, event)
            value = app.Slot7CheckBox.Value;
            app.control.CakeSlotChoice(9);
            
        end

        % Value changed function: Slot8CheckBox
        function CakeSlot8(app, event)
            value = app.Slot8CheckBox.Value;
            app.control.CakeSlotChoice(1);
            
        end

        % Value changed function: Slot9CheckBox
        function CakeSlot9(app, event)
            value = app.Slot9CheckBox.Value;
            app.control.CakeSlotChoice(5);
            
        end

        % Value changed function: Slot10CheckBox
        function CakeSlot10(app, event)
            value = app.Slot10CheckBox.Value;
            app.control.CakeSlotChoice(10);
            
        end

        % Value changed function: Slot11CheckBox
        function CakeSlot11(app, event)
            value = app.Slot11CheckBox.Value;
            app.control.CakeSlotChoice(2);
            
        end

        % Value changed function: Slot12CheckBox
        function CakeSlot12(app, event)
            value = app.Slot12CheckBox.Value;
            app.control.CakeSlotChoice(6);
            
        end

        % Value changed function: PaperColourDropDown
        function ColourChoice(app, event)
            value = app.PaperColourDropDown.Value;
            %if yellow 1, if blue 2, if green 3 etc
            %control.PaperColourChoice(colour);
            
        end

        % Callback function
        function UpdateJoint0(app, event)
            value = app.Joint0Slider.deg2rad(value);
            %N/A
        end

        % Value changed function: Joint1Slider
        function UpdateJoint1(app, event)
            value = app.Joint1Slider.Value;
            app.q_joint_slider(1,1)=deg2rad(value);
            %Make the value signify a q plan in the first point, using 6
            %points in total through the 6 sliders
        end

        % Value changed function: Joint2Slider
        function UpdateJoint2(app, event)
            value = app.Joint2Slider.Value;
            app.q_joint_slider(1,2)=deg2rad(value);
        end

        % Value changed function: Joint3Slider
        function UpdateJoint3(app, event)
            value = app.Joint3Slider.Value;
           app.q_joint_slider(1,3)=deg2rad(value);
            
        end

        % Value changed function: Joint4Slider
        function UpdateJoint4(app, event)
            value = app.Joint4Slider.Value;
            app.q_joint_slider(1,4)=deg2rad(value);
        end

        % Value changed function: Joint5Slider
        function UpdateJoint5(app, event)
            value = app.Joint5Slider.Value;
            app.q_joint_slider(1,5)=deg2rad(value);
            
        end

        % Value changed function: Joint6Slider
        function UpdateJoint6(app, event)
            value = app.Joint6Slider.Value;
            app.q_joint_slider(1,6)=deg2rad(value);
            
        end

        % Button pushed function: UpdateJointsButton
        function JointImplementUpdates(app, event)
            q_value = app.q_joint_slider
            app.control.RobotPoseAnimate(q_value);
            %app.control.robot.RMRC(q_value);
            
            T = app.control.robot.model.fkine(q_value);
            app.XEditField.Value = T(1,4);
            app.YEditField.Value = T(2,4);
            app.ZEditField.Value = T(3,4);
            eulZYX = rotm2eul(tform2rotm(T));
            app.RollEditField = eulZYX(1,1);
            app.PitchEditField = eulZYX(1,2);
            app.YawEditField = eulZYX(1,3);

        end

        % Value changed function: XEditField
        function EndEffectorXAim(app, event)
            value = app.XEditField.Value;
            app.x_goal = value;
        end

        % Value changed function: YEditField
        function EndEffectorYAim(app, event)
            value = app.YEditField.Value;
            app.y_goal = value;
        end

        % Value changed function: ZEditField
        function EndEffectorZAim(app, event)
            value = app.ZEditField.Value;
            app.z_goal = value;
        end

        % Button pushed function: JoystickControlButton
        function JoyConControl(app, event)
            %Activate content similar to Lab week 11
            app.control.robot.FreeMovement();
        end

        % Button pushed function: EResumeButton
        function EResumeButtonPushed(app, event)
            app.control.robot.stop_status = 0;
            app.control.robot.returnRobotJoints();
            % No Movement Tied
            app.control.robot.mode=1;
            %Move to Oven Door
            fprintf('Move To Oven \n');
            app.control.SpawnRMRCPoint(0.04,0.68,0.68, 0, -pi/2, pi/2, 5);
            pause(5);
            %Door Movement Tied
            app.control.robot.mode = 2;
            %Open Oven
            fprintf('Moving Oven \n');
            app.control.SpawnRMRCPoint(0.08,1.02,0.47, 0, -pi/2, pi/2, 5);
            pause(5);
            %No Movement Tied
            app.control.robot.mode = 1;
            fprintf('Move To Bottle \n');
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y,app.control.bottle_z, -pi/2, 0, pi/2);
            %Bottle Movement Tied
            app.control.robot.mode=3;
            fprintf('Bottle Turn \n');
            pause(5);
            app.control.SpawnRMRCPoint(app.control.bottle_x,app.control.bottle_y,app.control.bottle_z, -pi/2, 0, -pi/2, 5);
            %Function for each cake movement and pause
            pause(5);
            app.control.CakeLocationSpawn();
            fprintf('Return Bottle Location (Include Rotation in an arc) \n');
            app.control.SpawnRMRCPoint(app.control.tray_x,0.16,app.control.tableZ+0.145, -pi/2, 0, -pi/2, 5);
            %No Movement Tied
            pause(5);
            app.control.robot.mode=1;
            fprintf('Move to Tray \n');
            app.control.SpawnRMRCPoint(app.control.tray_x,app.control.tray_y+0.35,app.control.tableZ+0.05, -pi/2, 0, 0, 5);
            % Tray movement tied
            pause(5);
            app.control.robot.mode=4; 
            fprintf('Move Tray to Oven \n');
            app.control.SpawnRMRCPoint(0.3,0.4,0.6, -pi/2, 0, 0, 5);
            pause(5);
            %No Movement Tied
            app.control.robot.mode =1;
            %Move To Door New
            fprintf('Move To Oven \n');
            app.control.SpawnRMRCPoint(0.08,1.02,0.47, 0, -pi/2, pi/2, 5);
            %Door Movement Tied
            app.control.robot.mode = 2;
            %Move Door
            fprintf('Moving Oven \n');
            app.control.SpawnRMRCPoint(0.04,0.68,0.68, 0, -pi/2, pi/2, 5);
            %End
            
        end

        % Button pushed function: UpdatePositionButton
        function UpdatePositionButtonPushed(app, event)
            %use app.xyz_goal to get a q value
            %Then call app.control.robot.RMRC(q);
            app.control.SpawnRMRCPoint(app.x_goal, app.y_goal, app.z_goal, app.roll_goal, app.pitch_goal, app.yaw_goal, 1);
            %Alternatively derive q joints from these values
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            value = app.Joint7Slider.Value;
            app.q_joint_slider(1,7)=deg2rad(value);

        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            app.roll_goal = value;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.pitch_goal = value;
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;
            app.yaw_goal = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Slot1CheckBox = uicheckbox(app.UIFigure);
            app.Slot1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot1, true);
            app.Slot1CheckBox.Text = 'Slot 1';
            app.Slot2CheckBox = uicheckbox(app.UIFigure);
            app.Slot2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot2, true);
            app.Slot2CheckBox.Text = 'Slot 2';
            app.Slot3CheckBox = uicheckbox(app.UIFigure);
            app.Slot3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot3, true);
            app.Slot3CheckBox.Text = 'Slot 3';
            app.Slot4CheckBox = uicheckbox(app.UIFigure);
            app.Slot4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot4, true);
            app.Slot4CheckBox.Text = 'Slot 4';
            app.Slot5CheckBox = uicheckbox(app.UIFigure);
            app.Slot5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot5, true);
            app.Slot5CheckBox.Text = 'Slot 5';
            app.Slot6CheckBox = uicheckbox(app.UIFigure);
            app.Slot6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot6, true);
            app.Slot6CheckBox.Text = 'Slot 6';
            app.Slot7CheckBox = uicheckbox(app.UIFigure);
            app.Slot7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot7, true);
            app.Slot7CheckBox.Text = 'Slot 7';
            app.Slot8CheckBox = uicheckbox(app.UIFigure);
            app.Slot8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot8, true);
            app.Slot8CheckBox.Text = 'Slot 8';
            app.Slot9CheckBox = uicheckbox(app.UIFigure);
            app.Slot9CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot9, true);
            app.Slot9CheckBox.Text = 'Slot 9';
            app.Slot10CheckBox = uicheckbox(app.UIFigure);
            app.Slot10CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot10, true);
            app.Slot10CheckBox.Text = 'Slot 10';
            app.Slot11CheckBox = uicheckbox(app.UIFigure);
            app.Slot11CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot11, true);
            app.Slot11CheckBox.Text = 'Slot 11';
            app.Slot12CheckBox = uicheckbox(app.UIFigure);
            app.Slot12CheckBox.ValueChangedFcn = createCallbackFcn(app, @CakeSlot12, true);
            app.Slot12CheckBox.Text = 'Slot 12';
            app.PaperColourDropDownLabel = uilabel(app.UIFigure);
            app.PaperColourDropDownLabel.Text = 'Paper Colour';
            app.PaperColourDropDown = uidropdown(app.UIFigure);
            app.PaperColourDropDown.Items = {'Blue', 'Red', 'Yellow', 'Green'};
            app.PaperColourDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourChoice, true);
            app.PaperColourDropDown.Value = 'Yellow';
            app.OvenAssitaBotLabel = uilabel(app.UIFigure);
            app.OvenAssitaBotLabel.Text = 'Oven Assita-Bot';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-170 170];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint1, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-65 145];
            app.Joint2Slider.MajorTicks = [-65 -35 0 25 55 85 115 145];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint2, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-70 190];
            app.Joint3Slider.MajorTicks = [-70 -18 0 34 86 138 190];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint3, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-190 190];
            app.Joint4Slider.MajorTicks = [-190 -114 -38 0 38 114 190];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint4, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-135 135];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint5, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateJoint6, true);
            app.JointMovementLabel = uilabel(app.UIFigure);
            app.JointMovementLabel.Text = 'Joint Movement';
            app.EndEffectorControlLabel = uilabel(app.UIFigure);
            app.EndEffectorControlLabel.Text = 'End Effector Control';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorXAim, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorYAim, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorZAim, true);
            app.UpdateJointsButton = uibutton(app.UIFigure, 'push');
            app.UpdateJointsButton.ButtonPushedFcn = createCallbackFcn(app, @JointImplementUpdates, true);
            app.UpdateJointsButton.Text = 'Update Joints';
            app.UpdatePositionButton = uibutton(app.UIFigure, 'push');
            app.UpdatePositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePositionButtonPushed, true);
            app.UpdatePositionButton.Text = 'Update Position';
            app.CupcakePrepLabel = uilabel(app.UIFigure);
            app.CupcakePrepLabel.Text = 'Cupcake Prep';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @StartEvent, true);
            app.STARTButton.Text = 'START';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStop, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.JoystickControlButton = uibutton(app.UIFigure, 'push');
            app.JoystickControlButton.ButtonPushedFcn = createCallbackFcn(app, @JoyConControl, true);
            app.JoystickControlButton.Text = 'Joystick Control';
            app.EResumeButton = uibutton(app.UIFigure, 'push');
            app.EResumeButton.ButtonPushedFcn = createCallbackFcn(app, @EResumeButtonPushed, true);
            app.EResumeButton.Text = 'E-Resume';
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.Limits = [-360 360];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oven_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end